classdef proletariy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        XEditFieldLabel    matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        XEditFieldLabel_2  matlab.ui.control.Label
        XEditField         matlab.ui.control.NumericEditField
        YLabel_2           matlab.ui.control.Label
        EditField3         matlab.ui.control.NumericEditField
        YLabel             matlab.ui.control.Label
        YEditField         matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        EditField5         matlab.ui.control.NumericEditField
        Button             matlab.ui.control.Button
        Label              matlab.ui.control.Label
        Button_2           matlab.ui.control.Button
        Label_3            matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Button_3           matlab.ui.control.Button
        Label_4            matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        y1EditFieldLabel   matlab.ui.control.Label
        y1EditField        matlab.ui.control.NumericEditField
        x2EditFieldLabel   matlab.ui.control.Label
        x2EditField        matlab.ui.control.NumericEditField
        y2EditFieldLabel   matlab.ui.control.Label
        y2EditField        matlab.ui.control.NumericEditField
        x3EditFieldLabel   matlab.ui.control.Label
        x3EditField        matlab.ui.control.NumericEditField
        y3EditFieldLabel   matlab.ui.control.Label
        y3EditField        matlab.ui.control.NumericEditField
        x4EditFieldLabel   matlab.ui.control.Label
        x4EditField        matlab.ui.control.NumericEditField
        y4EditFieldLabel   matlab.ui.control.Label
        y4EditField        matlab.ui.control.NumericEditField
        x5EditFieldLabel   matlab.ui.control.Label
        x5EditField        matlab.ui.control.NumericEditField
        y5EditFieldLabel   matlab.ui.control.Label
        y5EditField        matlab.ui.control.NumericEditField
        x6EditFieldLabel   matlab.ui.control.Label
        x6EditField        matlab.ui.control.NumericEditField
        y6EditFieldLabel   matlab.ui.control.Label
        y6EditField        matlab.ui.control.NumericEditField
        x7EditFieldLabel   matlab.ui.control.Label
        x7EditField        matlab.ui.control.NumericEditField
        y7EditFieldLabel   matlab.ui.control.Label
        y7EditField        matlab.ui.control.NumericEditField
        x8EditFieldLabel   matlab.ui.control.Label
        x8EditField        matlab.ui.control.NumericEditField
        y8EditFieldLabel   matlab.ui.control.Label
        y8EditField        matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Label_8            matlab.ui.control.Label
        Label_9            matlab.ui.control.Label
        Label_10           matlab.ui.control.Label
        Label_11           matlab.ui.control.Label
        Label_12           matlab.ui.control.Label
        Label_13           matlab.ui.control.Label
        Label_14           matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global mX;
            global mY;
            global dX;
            global dY;
            global r;
            mX = app.EditField.Value;
            mY = app.YEditField.Value;
            dX = app.XEditField.Value;
            dY = app.EditField3.Value;
            r = app.EditField5.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global img;
            % при помощи данной переменной проверяем равенство значений a и d (коэф-ты масштаба)
            flag1 = 0;
            if (~isnan(img))
                a = app.EditField.Value;
                d = app.YEditField.Value;
                c = app.XEditField.Value;
                b = app.EditField3.Value;
                e = app.EditField5.Value;
                if a==d && a==c && a==b && d==c && d==b && c==b
                    a = a+0.0001;
                end
                % Определение матрицы аффинного преобразования
                % Матрица
                if(app.DropDown.Value == "Деформация")
                    T = [a b 0; c d 0; 0 0 1];
                    if a==0 || d == 0
                        flag1 = 1;
                    end
                elseif(app.DropDown.Value == "Поворот")
                    T = [cosd(e) -sind(e) 0; sind(e) cosd(e) 0; 0 0 1];
                end
                % если переменные a и d не равны, то будет произведена обработка
                if flag1 == 0
                    % Применение аффинного преобразования к изображению
                    transformed_image = apply_affine_transformation(img, T);
                    % Визуализация исходного и преобразованного изображений
                    subplot(1,2,1), imshow(img), title('Исходное изображение');
                    subplot(1,2,2), imshow(transformed_image), title('Результат преобразования');
                    app.Label.Text = ('Готово!');
                else
                    % При равенстве коэф-тов масштаба нулю необходимо вывести предупреждение
                    app.Label.Text = ('Масштаб не может быть равен 0');
                end
            else
                % Если переменная img будет пуста (когда не выбран файл)
                app.Label.Text = ('Сначала выберите изображение');
            end
                % Функция для 
                function transformed_image = apply_affine_transformation(image, transform_matrix)
                % Создание объекта affine2d с заданными матрицей преобразования и размерами изображения
                affine_transform = affine2d(transform_matrix);
                % Размеры изображения
                [rows, cols, ~] = size(image);
                % Получение координат углов изображения
                corners = [1, 1; 1, rows; cols, 1; cols, rows];
                % Применение аффинного преобразования к координатам углов
                transformed_corners = transformPointsForward(affine_transform, corners);
                % Нахождение границ нового изображения
                min_x = min(transformed_corners(:, 1));
                max_x = max(transformed_corners(:, 1));
                min_y = min(transformed_corners(:, 2));
                max_y = max(transformed_corners(:, 2));
                % Вычисление новых размеров изображения после преобразования
                new_cols = ceil(max_x) - floor(min_x) + 1;
                new_rows = ceil(max_y) - floor(min_y) + 1;
                % Создание нового изображения с учетом размеров после преобразования
                transformed_image = zeros(new_rows, new_cols, size(image, 3), class(image));
                % Применение аффинного преобразования к изображению
                [X, Y] = meshgrid(1:new_cols, 1:new_rows);
                [Xq, Yq] = transformPointsInverse(affine_transform, X(:) + min_x - 1, Y(:) + min_y - 1);
                Xq = reshape(Xq, [new_rows, new_cols]);
                Yq = reshape(Yq, [new_rows, new_cols]);
                % Интерполяция значений из исходного изображения к новым координатам
                for k = 1:size(image, 3)
                    transformed_image(:, :, k) = interp2(double(image(:, :, k)), Xq, Yq, 'linear', 0);
                end
            end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global img;
            % Переменная ext возвращает значение индекса матрицы разрешенных форматов
            [filename, pathname, ext] = uigetfile({'*.jpg';'*.png';'*.jpeg'}, 'Выберите изображение');
            % Если выбор изображения был отменен
            if isequal(filename,0) || isequal(pathname,0)
                app.Label.Text = ('Вы не выбрали изображение');
                % Разыменование img, поскольку файл не выбран
                img = nan; 
                return;
            end
            % Переменная ext должна вернуть индекс элемента из матрицы разрешенных форматов
            if (ext == 1 || ext == 2 || ext == 3)
                img = imread(fullfile(pathname, filename));
                app.Label.Text = ('Задайте параметры матрицы');
                return;
            else
                % При выборе неразрешенного формата ext вернет другое значение
                app.Label.Text = ('Выбран неверный тип файла');
                % Разыменование img, поскольку выбран неверный формат
                img = nan;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            global mX;
            global mY;
            global dX;
            global dY;
            global r;
            global flag;
            if flag ~= 1
                mX = app.EditField.Value;
                mY = app.YEditField.Value;
                dX = app.XEditField.Value;
                dY = app.EditField3.Value;
                disp("d");
            end
            if flag == 1
                r = app.EditField5.Value;
            end
            if value == "Поворот"
                flag = 1;
                app.EditField.Value = 0;
                app.XEditField.Value = 0;
                app.YEditField.Value = 0;
                app.EditField3.Value = 0;
                app.EditField5.Value = r;
            elseif value == "Деформация"
                app.EditField.Value = mX;
                app.YEditField.Value = mY;
                app.XEditField.Value = dX;
                app.EditField3.Value = dY;
                app.EditField5.Value = 0;
                flag = 0;
            end
                
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
                  global img;
                  if (~isnan(img))
                        x1 = app.EditField_2.Value;
                        y1 = app.y1EditField.Value;
                        x2 = app.x2EditField.Value;
                        y2 = app.y2EditField.Value;
                        x3 = app.x3EditField.Value;
                        y3 = app.y3EditField.Value;
                        x4 = app.x4EditField.Value;
                        y4 = app.y4EditField.Value;
                        x5 = app.x5EditField.Value;
                        y5 = app.y5EditField.Value;
                        x6 = app.x6EditField.Value;
                        y6 = app.y6EditField.Value;
                        x7 = app.x7EditField.Value;
                        y7 = app.y7EditField.Value;
                        x8 = app.x8EditField.Value;
                        y8 = app.y8EditField.Value;
                        % при помощи данной переменной проверяем равенство значений a и d (коэф-ты масштаба)
                        % Определение точек на исходном изображении (в порядке левый верхний, правый верхний, правый нижний, левый нижний)
                        % Задаем координаты исходных точек
                        src_points = [x1, y1; x2, y2; x3, y3; x4, y4]; % Пример исходных координат
                        % Задаем координаты точек, в которые мы хотим преобразовать исходные
                        dest_points = [x5, y5; x6, y6; x7, y7; x8, y8]; % Пример конечных координат

                        % Создаем матрицу преобразования
                        T = fitgeotrans(src_points, dest_points, 'projective');
                        % Применяем проективное аффинное преобразование к изображению
                        warped_image = imwarp(img, T);
                        subplot(1,2,1), imshow(img), title('Исходное изображение');
                        subplot(1,2,2), imshow(warped_image), title('Результат преобразования');
                        app.Label.Text = ('Готово!');
                  else
                        % Если переменная img будет пуста (когда не выбран файл)
                        app.Label.Text = ('Сначала выберите изображение');
                  end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = {'Масштаб по оси X'; ''};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.XEditFieldLabel_2 = uilabel(app.UIFigure);
            app.XEditFieldLabel_2.Text = 'Деформация по X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 0.5;
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Деформация по Y';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Value = 0.8;
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Масштаб по Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Угол поворота';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Обычное преобразование';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'Загрузить изображение'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Выбор функции';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Деформация', 'Поворот'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Деформация';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Проективное преобразование';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'х1';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField.Value = 800;
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x3EditFieldLabel = uilabel(app.UIFigure);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField.Value = 800;
            app.y3EditFieldLabel = uilabel(app.UIFigure);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.UIFigure, 'numeric');
            app.y3EditField.Value = 800;
            app.x4EditFieldLabel = uilabel(app.UIFigure);
            app.x4EditFieldLabel.Text = 'x4';
            app.x4EditField = uieditfield(app.UIFigure, 'numeric');
            app.y4EditFieldLabel = uilabel(app.UIFigure);
            app.y4EditFieldLabel.Text = 'y4';
            app.y4EditField = uieditfield(app.UIFigure, 'numeric');
            app.y4EditField.Value = 800;
            app.x5EditFieldLabel = uilabel(app.UIFigure);
            app.x5EditFieldLabel.Text = 'x5';
            app.x5EditField = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField.Value = -20;
            app.y5EditFieldLabel = uilabel(app.UIFigure);
            app.y5EditFieldLabel.Text = 'y5';
            app.y5EditField = uieditfield(app.UIFigure, 'numeric');
            app.y5EditField.Value = -120;
            app.x6EditFieldLabel = uilabel(app.UIFigure);
            app.x6EditFieldLabel.Text = 'x6';
            app.x6EditField = uieditfield(app.UIFigure, 'numeric');
            app.x6EditField.Value = 800;
            app.y6EditFieldLabel = uilabel(app.UIFigure);
            app.y6EditFieldLabel.Text = 'y6';
            app.y6EditField = uieditfield(app.UIFigure, 'numeric');
            app.x7EditFieldLabel = uilabel(app.UIFigure);
            app.x7EditFieldLabel.Text = 'x7';
            app.x7EditField = uieditfield(app.UIFigure, 'numeric');
            app.x7EditField.Value = 800;
            app.y7EditFieldLabel = uilabel(app.UIFigure);
            app.y7EditFieldLabel.Text = 'y7';
            app.y7EditField = uieditfield(app.UIFigure, 'numeric');
            app.y7EditField.Value = 750;
            app.x8EditFieldLabel = uilabel(app.UIFigure);
            app.x8EditFieldLabel.Text = 'x8';
            app.x8EditField = uieditfield(app.UIFigure, 'numeric');
            app.x8EditField.Value = -20;
            app.y8EditFieldLabel = uilabel(app.UIFigure);
            app.y8EditFieldLabel.Text = 'y8';
            app.y8EditField = uieditfield(app.UIFigure, 'numeric');
            app.y8EditField.Value = 850;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '_________________________________________________________________________________';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Проективное аффинное преобразование';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'Координаты левого столбца отвечают за контрольные '; 'точки исходного изображения, а правого столбаца за '; 'контрольные точки преобразованного изображения.'};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'Вы можете перемещать координаты как угодно'; 'однако соблюдайте рамки дозволенного. Не стоит'; 'вводить случайные значения, действуйте обдуманно.'};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'Если появляются какие-либо ошибки, то проверьте'; 'корректность введенных данных и попробуйте снова.'};
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '1 точка - левый верхний угол';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '2 точка - правый верхний угол';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '3 точка - правый нижний угол';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '4 точка - левый нижний угол';
        end
    end

    methods (Access = public)

        % Construct app
        function app = proletariy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end